classdef app_heartbeat_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LatidoLampLabel            matlab.ui.control.Label
        LatidoLamp                 matlab.ui.control.Lamp
        SeleccioneunarchivoButton  matlab.ui.control.StateButton
        ReproducirButton           matlab.ui.control.StateButton
        UIOriginalSignal           matlab.ui.control.UIAxes
        UIFilteredSignal           matlab.ui.control.UIAxes
        UIProcessedSignal          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        signalStruct;       % estructura que se obtiene al cargar un achivo .mat
        signal;             % señal ecg que se carga directamente de un archivo
        timedEcg;           % señal ecg que va creciendo con el tiempo
        
        % aquí van variables necesarias para poder hacer los cálculos de la
        % señal
        
        Fs;     % frecuencia de muestreo 
        L;      % longitud de la señal
        T;      % perido de la señal 
        t;      % vector de tiempo de la señal ecg
              
        
        file;       % nombre del archivo 
        path;       % ruta al archivo 
        
        beep;       % sonido de un beep para indicar detección de complejo QRS
        beepFs;     % frecuencia de muestreo del audio de beep
        
        % variable para detectar si la señal fue inicializada
        initialized = false;    
        
    end
    
    methods (Access = public)
        
        function startSimulation(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% SECCIÓN DE INICIALIZACIÓN DE VARIABLES ÚTILES PARA EL        %
            %% ALGORITMO                                                    %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.L = length(app.signal);         % Longitud de la señal (numero de muestras)
            app.T = app.L/app.Fs;               % Duración de la señal
            app.t = linspace(0, app.T, app.L);  % Vector de tiempo
            
            % ancho de la ventana que se va moviendo y que va integrando
            % el ancho de la ventana es de 150 ms entonces se debe tener en cuenta
            % la frecuencia de muestreo de la señal particular para calcular el número
            % de muestras que va a contener la ventana.
            
            n = ceil(150/((app.Fs.^(-1))*1000));
            
            % mitad del número de muestras que deben haber para iniciar bien el
            % algoritmo esto porque se requiere que hayan 2 latidos detectados para
            % calibrar bien todos los umbrales y los estimados del intervalo RR
            % esto es porque se da 360 ms para detectar un latido 
            
            RRavg2 = ceil( (400/((app.Fs.^(-1))*1000)) );
            RRavg1 = 0;
            
            % lista de 8 intervalos RR más reciencientes 
            RR1 = zeros(8,1);
            
            % lista de 8 intervalos RR más recientes dentro de RR-high y RR-low
            RR2 = [ RRavg2 ];
            
            % conjunto de umbrales para la señal filtrada y la integrada
            % respectivamente
            thresholdSetF = [];
            thresholdSetI = [];
            
            
            % media del pico de la señal filtrada
            SPKF = 0;
            
            % media del pico de ruido de la señal  filtrada 
            NPKF = 0;
            
            % media del pico de la señal integrada
            SPKI = 0;
            
            % media del pico del ruido de la señal integrada
            NPKI = 0;

            
            % variable de lectura de la señal para simulaciones
            localSignal = zeros(L,1);
            localFilteredSignal = zeros(L,1);
            derivative = zeros(L,1);

            %% limpiar la señal    
            % frecuencias de corte 
            fc1 = 15; 
            fc2 = 5;
            
            % parametros para filtro pasa bajas con frecuencia de corte 15 Hz
            [b1,a1] = butter(4, fc1/(Fs/2));
            
            % parametros para filtro pasa altas con frecuencia de corte 5 Hz
            [b2,a2] = butter(4, fc2/(Fs/2), 'high');

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SeleccioneunarchivoButton
        function SeleccioneunarchivoButtonValueChanged(app, event)
            %value = app.SeleccioneunarchivoButton.Value;
            
            [app.file, app.path] = uigetfile('*.mat');
            filepath = fullfile(app.path, app.file);

            app.signalStruct = load(filepath, '-mat');
            app.signal = app.signalStruct.ecg;
            app.Fs = app.signalStruct.Fs;
            
            % si no se ha inicializado inicializa el beep de la señal de
            % audio
            
            if (~ app.initialized )
                [app.beep, app.beepFs] = audioread('beep1.wav');
                app.initialized = true;
            end


        end

        % Value changed function: ReproducirButton
        function ReproducirButtonValueChanged(app, event)
            value = app.ReproducirButton.Value;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatidoLampLabel = uilabel(app.UIFigure);
            app.LatidoLampLabel.Text = 'Latido';
            app.LatidoLamp = uilamp(app.UIFigure);
            app.SeleccioneunarchivoButton = uibutton(app.UIFigure, 'state');
            app.SeleccioneunarchivoButton.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunarchivoButtonValueChanged, true);
            app.SeleccioneunarchivoButton.Text = 'Seleccione un archivo';
            app.ReproducirButton = uibutton(app.UIFigure, 'state');
            app.ReproducirButton.ValueChangedFcn = createCallbackFcn(app, @ReproducirButtonValueChanged, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.UIOriginalSignal = uiaxes(app.UIFigure);
            title(app.UIOriginalSignal, 'Señal ECG original')
            xlabel(app.UIOriginalSignal, 'tiempo (secs)')
            ylabel(app.UIOriginalSignal, 'mV')
            app.UIFilteredSignal = uiaxes(app.UIFigure);
            title(app.UIFilteredSignal, 'Señal ECG filtrada')
            xlabel(app.UIFilteredSignal, 'tiempo (secs)')
            ylabel(app.UIFilteredSignal, 'mV')
            app.UIProcessedSignal = uiaxes(app.UIFigure);
            title(app.UIProcessedSignal, 'Integral de la derivada al cuadrado de la señal ECG filtrada')
            xlabel(app.UIProcessedSignal, 'X')
            ylabel(app.UIProcessedSignal, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_heartbeat_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end